classdef Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        Menu_2                         matlab.ui.container.Menu
        ModeMenu                       matlab.ui.container.Menu
        SweepMenu                      matlab.ui.container.Menu
        Menu3                          matlab.ui.container.Menu
        WiFiMenu                       matlab.ui.container.Menu
        WiFiPanel                      matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        CurrentPathEditField           matlab.ui.control.EditField
        CurrentPathEditFieldLabel      matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        TabGroup                       matlab.ui.container.TabGroup
        EVMTab                         matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        UIAxes_EVM                     matlab.ui.control.UIAxes
        ACPRTab                        matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        UIAxes_ACPR                    matlab.ui.control.UIAxes
        ImportButton                   matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        TextArea                       matlab.ui.control.TextArea
        RefreshButton                  matlab.ui.control.Button
        SEMButton                      matlab.ui.control.Button
        BNDGButton                     matlab.ui.control.Button
        ExtraOutputLossEditFieldLabel  matlab.ui.control.Label
        ExtraOutputLossEditField       matlab.ui.control.NumericEditField
        InputEVMFloorEditFieldLabel    matlab.ui.control.Label
        InputEVMFloorEditField         matlab.ui.control.NumericEditField
        SweepPanel                     matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ColumnButton_2                 matlab.ui.control.RadioButton
        RowButton                      matlab.ui.control.RadioButton
        Swp1Label                      matlab.ui.control.Label
        Swp1DropDown                   matlab.ui.control.DropDown
        Swp2Label                      matlab.ui.control.Label
        Swp2DropDown                   matlab.ui.control.DropDown
        Swp3Label                      matlab.ui.control.Label
        Swp3DropDown                   matlab.ui.control.DropDown
        ListBox_2                      matlab.ui.control.ListBox
        CurrentPathEditField_2Label    matlab.ui.control.Label
        CurrentPathEditField_2         matlab.ui.control.EditField
        ImportButton_2                 matlab.ui.control.Button
        SwpnLabel                      matlab.ui.control.Label
        ListBox_3                      matlab.ui.control.ListBox
        ParametersPresetButtonGroup    matlab.ui.container.ButtonGroup
        HLmodeButton                   matlab.ui.control.RadioButton
        HEmodeButton                   matlab.ui.control.RadioButton
        BNDGButton_2                   matlab.ui.control.RadioButton
        WiFiLabel                      matlab.ui.control.Label
        CellLabel                      matlab.ui.control.Label
        PH1Button                      matlab.ui.control.RadioButton
        PH2Button                      matlab.ui.control.RadioButton
        PH3Button                      matlab.ui.control.RadioButton
        PconButton                     matlab.ui.control.RadioButton
        PH4Button                      matlab.ui.control.RadioButton
        NoneButton                     matlab.ui.control.RadioButton
        MoveUpButton                   matlab.ui.control.Button
        MoveDownButton                 matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        DeleteButton                   matlab.ui.control.Button
        MoveUpButton_2                 matlab.ui.control.Button
        MoveDownButton_2               matlab.ui.control.Button
        ListBox_4                      matlab.ui.control.ListBox
        ConditionEditFieldLabel        matlab.ui.control.Label
        ConditionEditField             matlab.ui.control.NumericEditField
        SweepPresetButtonGroup         matlab.ui.container.ButtonGroup
        HLDACButton                    matlab.ui.control.RadioButton
        WBCButton                      matlab.ui.control.RadioButton
        TGDCButton                     matlab.ui.control.RadioButton
        WiFiLabel_2                    matlab.ui.control.Label
        CellLabel_2                    matlab.ui.control.Label
        DACButton_2                    matlab.ui.control.RadioButton
        PH2Button_2                    matlab.ui.control.RadioButton
        PH3Button_2                    matlab.ui.control.RadioButton
        NoneButton_2                   matlab.ui.control.RadioButton
        HEDACButton                    matlab.ui.control.RadioButton
        PlotButton                     matlab.ui.control.Button
        NewPlotButton                  matlab.ui.control.Button
        InstantPlotSwitchLabel         matlab.ui.control.Label
        InstantPlotSwitch              matlab.ui.control.RockerSwitch
        DPDSwitchLabel                 matlab.ui.control.Label
        DPDSwitch                      matlab.ui.control.RockerSwitch
        ReadyLampLabel                 matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        data % Description
    end
    
    properties (Access = private)
        dataidx % Description
    end
    
    methods (Access = private)
        
        function UpdateData(app)
            if ismissing(app.data.pname)
                return
            end
            app.CurrentPathEditField.Value=app.data.pname;            
            app.UITable.Data=app.data.unique_cases;
            app.UITable.ColumnName=app.data.unique_cases.Properties.VariableNames;
            app.UITable.RowName=app.data.unique_cases.Properties.RowNames;
            assignin('base','dataA',app.data)
        end
        
        function updatePlots(app)
            if app.TabGroup.SelectedTab.Title=="EVM"
                cat="EVM";
            elseif app.TabGroup.SelectedTab.Title=="ACPR"
                cat="ACPR";
            end
            
            persistent fig
            persistent caseID
            if isempty(caseID)
                caseID.EVM=[];
                caseID.ACPR=[];
            end
            if isempty(fig)
                fig.EVM=[];
                fig.ACPR=[];
            end
            

            if isempty(caseID.(cat)) % if this is the first time plotting
                evalCaseID=app.dataidx;
                delCaseID=[];
            else
                evalCaseID=app.dataidx(~ismember(app.dataidx,caseID.(cat)));
                delCaseID=caseID.(cat)(~ismember(caseID.(cat),app.dataidx));
            end
            caseID.(cat)=app.dataidx; % update caseID
            
            if ~isempty(delCaseID)
                for i=1:size(delCaseID)
                    tagnum=string(delCaseID(i));
                    plotnum=strcat("plot",tagnum);
                    if isfield(fig.(cat),plotnum)
                        fig.(cat)=rmfield(fig.(cat),plotnum);
                        delete(findobj(fig.(cat).ax,'tag',tagnum));
                    end
                end
            end
            
            if ~isempty(evalCaseID)
                if ~isfield(fig.(cat),"ax")
                    fig.(cat).ax=app.(strcat("UIAxes_",cat));
                end
                fig.(cat)=plot_EVMACPR(app.data,evalCaseID,cat,fig.(cat));
                fig.(cat).leg.FontSize=12;
            end

            %-- Marker,Color,LineStyle Set
            newcolors = {'#F00';'#00F';'#0B0';'#F0F';'#FA0';'#000'};
            newlinestyles = {'-';'-.';'--'};
            newmarkers={'none'};

            hlines=findobj(fig.(cat).ax,'Type','line');
            hlines=flip(hlines);
            
            % color set - repeats color
            sizehlines=size(hlines,1);
            sizecolors=size(newcolors,1);
            sizelinestyles=size(newlinestyles,1);
            mult=floor(sizehlines/sizecolors)+1;
            
            % Marker
            newmarkers=repmat(newmarkers,sizehlines,1);
            
            % Color
            orders=repmat((1:sizecolors)',mult,1);
            orders_colors=orders(1:sizehlines);
            newcolors=newcolors(orders_colors,:);
            
            % LineStyle
            orders=repmat((1:sizelinestyles)',sizecolors,1);
            orders_linestyles=sortrows(orders,'ascend');
            orders_linestyles=repmat(orders_linestyles,mult,1);
            orders_linestyles=orders_linestyles(1:sizehlines,:);
            newlinestyles=newlinestyles(orders_linestyles);
            
            % set color/LineStyle/Marker
            set(hlines,{'color'},newcolors)
            set(hlines,{'LineStyle'},newlinestyles)
            set(hlines,{'Marker'},newmarkers)
        end
        
        function AllPanelDisable(app)
            app.SweepPanel.Enable='off';
            app.SweepPanel.Visible='off';
            app.WiFiPanel.Enable='off';
            app.WiFiPanel.Visible='off';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.SelectionType = 'row';
            app.UITable.Multiselect='on';
            app.UITable.ColumnSortable=true;
            
            AllPanelDisable(app)
        end

        % Callback function
        function ImportButtonPushed(app, event)
            if isempty(app.CurrentPathEditField.Value)
                temp_data=WiFi('\\rdsna01\wlsnet\Bourda&Hagley\05_User\Sungjun\LP_test_data');
            else
                temp_data=WiFi(app.CurrentPathEditField.Value);
            end
            if ~isempty(temp_data.data)
                app.data=temp_data;
            end
            UpdateData(app)
            
        end

        % Callback function
        function UITableCellSelection(app, event)
            indices = event.Indices;
            selectedidx=unique(indices(:,1),'stable');
%             selectedTab = app.TabGroup.SelectedTab.Title;
            app.dataidx=str2double(app.UITable.RowName(selectedidx)); % if filtered, then idx changes. so calling the rowname.
            
            updatePlots(app)

        end

        % Callback function
        function TabGroupSelectionChanged(app, event)
%             selectedTab = app.TabGroup.SelectedTab.Title;
%             if selectedTab=="EVM"
%                 app.UITable.Multiselect='on';
%             elseif selectedTab=="ACPR"
%                 app.UITable.Multiselect='on';
%             end
            updatePlots(app)
        end

        % Callback function
        function RefreshButtonPushed(app, event)
            if isempty(app.CurrentPathEditField.Value)
                return
            end
%             app.data=WiFi(app.data.pname,"DirectImport");
            app.data=WiFi(app.CurrentPathEditField.Value,"DirectImport");
            UpdateData(app)
        end

        % Callback function
        function SEMButtonPushed(app, event)
            plot_SEM(app.data,app.dataidx);
        end

        % Callback function
        function BNDGButtonPushed(app, event)
            plot_BNDG(app.data,app.dataidx,app.ExtraOutputLossEditField.Value);
        end

        % Callback function
        function InputEVMFloorEditFieldValueChanged(app, event)
            value = app.InputEVMFloorEditField.Value;
            app.data.EVMFloor=value;
        end

        % Callback function
        function ExtraOutputLossEditFieldValueChanged(app, event)
            value = app.ExtraOutputLossEditField.Value;
            app.data.BNDGLoss=value;
        end

        % Menu selected function: WiFiMenu
        function WiFiMenuSelected(app, event)
            AllPanelDisable(app)
            app.WiFiPanel.Enable='on';
            app.WiFiPanel.Visible='on';
        end

        % Menu selected function: SweepMenu
        function SweepMenuSelected(app, event)
            AllPanelDisable(app)
            app.SweepPanel.Enable='on';
            app.SweepPanel.Visible='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.Menu_2 = uimenu(app.FileMenu);
            app.Menu_2.Text = 'Menu';
            app.ModeMenu = uimenu(app.UIFigure);
            app.ModeMenu.Text = 'Mode';
            app.SweepMenu = uimenu(app.ModeMenu);
            app.SweepMenu.MenuSelectedFcn = createCallbackFcn(app, @SweepMenuSelected, true);
            app.SweepMenu.Text = 'Sweep';
            app.Menu3 = uimenu(app.ModeMenu);
            app.Menu3.Text = 'Menu3';
            app.WiFiMenu = uimenu(app.ModeMenu);
            app.WiFiMenu.MenuSelectedFcn = createCallbackFcn(app, @WiFiMenuSelected, true);
            app.WiFiMenu.Text = 'WiFi';
            app.WiFiPanel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.WiFiPanel);
            app.CurrentPathEditField = uieditfield(app.GridLayout, 'text');
            app.CurrentPathEditFieldLabel = uilabel(app.GridLayout);
            app.CurrentPathEditFieldLabel.Text = 'Current Path:';
            app.ListBox = uilistbox(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.EVMTab = uitab(app.TabGroup);
            app.EVMTab.Title = 'EVM';
            app.GridLayout2 = uigridlayout(app.EVMTab);
            app.UIAxes_EVM = uiaxes(app.GridLayout2);
            title(app.UIAxes_EVM, 'EVM')
            xlabel(app.UIAxes_EVM, 'X')
            ylabel(app.UIAxes_EVM, 'Y')
            zlabel(app.UIAxes_EVM, 'Z')
            app.ACPRTab = uitab(app.TabGroup);
            app.ACPRTab.Title = 'ACPR';
            app.GridLayout3 = uigridlayout(app.ACPRTab);
            app.UIAxes_ACPR = uiaxes(app.GridLayout3);
            title(app.UIAxes_ACPR, 'ACPR')
            xlabel(app.UIAxes_ACPR, 'X')
            ylabel(app.UIAxes_ACPR, 'Y')
            zlabel(app.UIAxes_ACPR, 'Z')
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.Text = 'Import';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.TextArea = uitextarea(app.GridLayout);
            app.RefreshButton = uibutton(app.GridLayout, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.SEMButton = uibutton(app.GridLayout, 'push');
            app.SEMButton.Text = 'SEM';
            app.BNDGButton = uibutton(app.GridLayout, 'push');
            app.BNDGButton.Text = 'BNDG';
            app.ExtraOutputLossEditFieldLabel = uilabel(app.GridLayout);
            app.ExtraOutputLossEditFieldLabel.Text = 'Extra Output Loss';
            app.ExtraOutputLossEditField = uieditfield(app.GridLayout, 'numeric');
            app.ExtraOutputLossEditField.Value = 14.5;
            app.InputEVMFloorEditFieldLabel = uilabel(app.GridLayout);
            app.InputEVMFloorEditFieldLabel.Text = 'Input EVM Floor';
            app.InputEVMFloorEditField = uieditfield(app.GridLayout, 'numeric');
            app.InputEVMFloorEditField.Value = -9999;
            app.SweepPanel = uipanel(app.UIFigure);
            app.GridLayout4 = uigridlayout(app.SweepPanel);
            app.ButtonGroup = uibuttongroup(app.GridLayout4);
            app.ColumnButton_2 = uiradiobutton(app.ButtonGroup);
            app.ColumnButton_2.Text = 'Column';
            app.ColumnButton_2.Value = true;
            app.RowButton = uiradiobutton(app.ButtonGroup);
            app.RowButton.Text = 'Row';
            app.Swp1Label = uilabel(app.GridLayout4);
            app.Swp1Label.Text = 'Swp 1';
            app.Swp1DropDown = uidropdown(app.GridLayout4);
            app.Swp2Label = uilabel(app.GridLayout4);
            app.Swp2Label.Text = 'Swp 2';
            app.Swp2DropDown = uidropdown(app.GridLayout4);
            app.Swp3Label = uilabel(app.GridLayout4);
            app.Swp3Label.Text = 'Swp 3';
            app.Swp3DropDown = uidropdown(app.GridLayout4);
            app.ListBox_2 = uilistbox(app.GridLayout4);
            app.CurrentPathEditField_2Label = uilabel(app.GridLayout4);
            app.CurrentPathEditField_2Label.Text = 'Current Path:';
            app.CurrentPathEditField_2 = uieditfield(app.GridLayout4, 'text');
            app.ImportButton_2 = uibutton(app.GridLayout4, 'push');
            app.ImportButton_2.Text = 'Import';
            app.SwpnLabel = uilabel(app.GridLayout4);
            app.SwpnLabel.Text = 'Swp n';
            app.ListBox_3 = uilistbox(app.GridLayout4);
            app.ParametersPresetButtonGroup = uibuttongroup(app.GridLayout4);
            app.ParametersPresetButtonGroup.Title = 'Parameters Preset';
            app.HLmodeButton = uiradiobutton(app.ParametersPresetButtonGroup);
            app.HLmodeButton.Text = 'HL mode';
            app.HEmodeButton = uiradiobutton(app.ParametersPresetButtonGroup);
            app.HEmodeButton.Text = 'HE mode';
            app.BNDGButton_2 = uiradiobutton(app.ParametersPresetButtonGroup);
            app.BNDGButton_2.Text = 'BNDG';
            app.WiFiLabel = uilabel(app.ParametersPresetButtonGroup);
            app.WiFiLabel.Text = 'WiFi';
            app.CellLabel = uilabel(app.ParametersPresetButtonGroup);
            app.CellLabel.Text = 'Cell';
            app.PH1Button = uiradiobutton(app.ParametersPresetButtonGroup);
            app.PH1Button.Text = 'PH1';
            app.PH2Button = uiradiobutton(app.ParametersPresetButtonGroup);
            app.PH2Button.Text = 'PH2';
            app.PH3Button = uiradiobutton(app.ParametersPresetButtonGroup);
            app.PH3Button.Text = 'PH3';
            app.PconButton = uiradiobutton(app.ParametersPresetButtonGroup);
            app.PconButton.Text = 'Pcon';
            app.PH4Button = uiradiobutton(app.ParametersPresetButtonGroup);
            app.PH4Button.Text = 'PH4';
            app.NoneButton = uiradiobutton(app.ParametersPresetButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.MoveUpButton = uibutton(app.GridLayout4, 'push');
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.GridLayout4, 'push');
            app.MoveDownButton.Text = 'Move Down';
            app.AddButton = uibutton(app.GridLayout4, 'push');
            app.AddButton.Text = 'Add >>';
            app.DeleteButton = uibutton(app.GridLayout4, 'push');
            app.DeleteButton.Text = '<< Delete';
            app.MoveUpButton_2 = uibutton(app.GridLayout4, 'push');
            app.MoveUpButton_2.Text = 'Move Up';
            app.MoveDownButton_2 = uibutton(app.GridLayout4, 'push');
            app.MoveDownButton_2.Text = 'Move Down';
            app.ListBox_4 = uilistbox(app.GridLayout4);
            app.ConditionEditFieldLabel = uilabel(app.GridLayout4);
            app.ConditionEditFieldLabel.Text = 'Condition: ';
            app.ConditionEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SweepPresetButtonGroup = uibuttongroup(app.GridLayout4);
            app.SweepPresetButtonGroup.Title = 'Sweep Preset';
            app.HLDACButton = uiradiobutton(app.SweepPresetButtonGroup);
            app.HLDACButton.Text = 'HL DAC';
            app.WBCButton = uiradiobutton(app.SweepPresetButtonGroup);
            app.WBCButton.Text = 'WBC';
            app.TGDCButton = uiradiobutton(app.SweepPresetButtonGroup);
            app.TGDCButton.Text = 'TGDC';
            app.WiFiLabel_2 = uilabel(app.SweepPresetButtonGroup);
            app.WiFiLabel_2.Text = 'WiFi';
            app.CellLabel_2 = uilabel(app.SweepPresetButtonGroup);
            app.CellLabel_2.Text = 'Cell';
            app.DACButton_2 = uiradiobutton(app.SweepPresetButtonGroup);
            app.DACButton_2.Text = 'DAC';
            app.PH2Button_2 = uiradiobutton(app.SweepPresetButtonGroup);
            app.PH2Button_2.Text = 'PH2';
            app.PH3Button_2 = uiradiobutton(app.SweepPresetButtonGroup);
            app.PH3Button_2.Text = 'PH3';
            app.NoneButton_2 = uiradiobutton(app.SweepPresetButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.HEDACButton = uiradiobutton(app.SweepPresetButtonGroup);
            app.HEDACButton.Text = 'HE DAC';
            app.PlotButton = uibutton(app.GridLayout4, 'push');
            app.PlotButton.Text = 'Plot';
            app.NewPlotButton = uibutton(app.GridLayout4, 'push');
            app.NewPlotButton.Text = 'New Plot';
            app.InstantPlotSwitchLabel = uilabel(app.GridLayout4);
            app.InstantPlotSwitchLabel.Text = 'Instant Plot';
            app.InstantPlotSwitch = uiswitch(app.GridLayout4, 'rocker');
            app.DPDSwitchLabel = uilabel(app.GridLayout4);
            app.DPDSwitchLabel.Text = 'DPD';
            app.DPDSwitch = uiswitch(app.GridLayout4, 'rocker');
            app.DPDSwitch.Value = 'On';
            app.ReadyLampLabel = uilabel(app.GridLayout4);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.GridLayout4);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end